classdef CommunicationLab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        ConnectButton     matlab.ui.control.Button
        DisconnectButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        s
    end
    
    methods (Access = private)
        function connect(app)
            open_ports = instrfind('Type', 'serial', 'Statuts', 'open')
            if ~isempty(open_ports)
                fclose(open_ports);
            end
            app.s = serial("COM11", "BaudRate", 9600, "Terminator", 10,...
                "BytesAvailableFcnMode", "terminator","BytesAvailableFcn", @app.bytesRecieved)
            fopen(app.s);
            fprintf("\nConnected!\n");
        end
        function disconnect(app)
            fclose(app.s);
            fprintf("\nDisconnected!\n");
        end
        
        
        function bytesRecieved(app, serialObj, eventData)
            message = fscanf(serialObj);
            fprintf("Recieved: %s", message);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.connect()
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.connect()
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.disconnect()
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.disconnect()
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CommunicationLab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end